classdef Moving_Comapny_Robots_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MovingCompanyRobotsUIFigure  matlab.ui.Figure
        BotBlueToothConnectionPanel  matlab.ui.container.Panel
        Lamp1                        matlab.ui.control.Lamp
        Lamp2                        matlab.ui.control.Lamp
        Lamp3                        matlab.ui.control.Lamp
        Lamp4                        matlab.ui.control.Lamp
        Bot1DropDownLabel            matlab.ui.control.Label
        Bot1DropDown                 matlab.ui.control.DropDown
        Bot2DropDownLabel            matlab.ui.control.Label
        Bot2DropDown                 matlab.ui.control.DropDown
        Bot3DropDownLabel            matlab.ui.control.Label
        Bot3DropDown                 matlab.ui.control.DropDown
        Bot4DropDownLabel            matlab.ui.control.Label
        Bot4DropDown                 matlab.ui.control.DropDown
        ConnectButton_1              matlab.ui.control.Button
        ConnectButton_2              matlab.ui.control.Button
        ConnectButton_3              matlab.ui.control.Button
        ConnectButton_4              matlab.ui.control.Button
        ReScanButton                 matlab.ui.control.Button
        SpeedCalibrationButton       matlab.ui.control.Button
        ControlsPanel                matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        FormationControlTab          matlab.ui.container.Tab
        BoxButton                    matlab.ui.control.Button
        CylinderButton               matlab.ui.control.Button
        TButton                      matlab.ui.control.Button
        NavigationTab                matlab.ui.container.Tab
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        SetDestinationLabel          matlab.ui.control.Label
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        BeginButton                  matlab.ui.control.Button
        FindPathButton               matlab.ui.control.Button
        ManualTab                    matlab.ui.container.Tab
        BotDropDownLabel             matlab.ui.control.Label
        BotDropDown                  matlab.ui.control.DropDown
        WASDControlButton            matlab.ui.control.StateButton
        ForwardButton                matlab.ui.control.Button
        RotateRight90Button          matlab.ui.control.Button
        RotateLeft90Button           matlab.ui.control.Button
        TicksEditFieldLabel          matlab.ui.control.Label
        TicksEditField               matlab.ui.control.NumericEditField
        Panel                        matlab.ui.container.Panel
        TextArea                     matlab.ui.control.TextArea
        ResetButton                  matlab.ui.control.Button
        TabGroup2                    matlab.ui.container.TabGroup
        NavigationTab_2              matlab.ui.container.Tab
        Map                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        
       % Bluetooth Sockets and grabbing scanned namees from instrument toolbox
       Bot1bt; Bot2bt; Bot3bt; Bot4bt;turn90 =125;
       btNames = instrhwinfo('Bluetooth');
       
       %Forward encoder values *100 and okaysignal read
       encval=1;read;
       
       % WASD value used to confirm manual control with WASD
       wasdvalue
       
       % Initilize Occupancy Grid for Bots to fallow edit map.xls to change the map
       MAP = xlsread('map.xlsx')
       %Goal Register
       GoalRegister=xlsread('Goal.xlsx');
       % Navigation Parameters
       sizeofbot =8;
       destx=16;desty=40;
       StartX=50;
       StartY= 99;
       OptimalPath;Transpose;
       finishedpath = 0;
       %U used to update Textbox in UI
       textarray;
       t = 0;

    end


    methods (Access = public)
        
        % Function to add text to textbox
        function addText(app,msg)
            
            app.t = app.t+1;
           
            app.textarray = fliplr(app.textarray);
            
            app.textarray{app.t} = msg;
            
            app.textarray = fliplr(app.textarray);
            
            app.TextArea.Value = app.textarray;
                
        end


        function OptimalPath=astarfunc(~,StartX,StartY,MAP,GoalRegister,maxsize)
            %FINDING ASTAR PATH IN AN OCCUPANCY GRID


            % Preallocation of Matrices
            [Height,Width]=size(MAP);               %Height and width of matrix
            GScore=zeros(Height,Width);           %Matrix keeping track of G-scores 
            FScore=single(inf(Height,Width));     %Matrix keeping track of F-scores (only open list) 
            Hn=single(zeros(Height,Width));       %Heuristic matrix
            OpenMAT=int8(zeros(Height,Width));    %Matrix keeping of open grid cells
            ClosedMAT=int8(zeros(Height,Width));  %Matrix keeping track of closed grid cells
            ClosedMAT(MAP==1)=1;                  %Adding object-cells to closed matrix
            ParentX=int16(zeros(Height,Width));   %Matrix keeping track of X position of parent
            ParentY=int16(zeros(Height,Width));   %Matrix keeping track of Y position of parent
            
            %Setting up neighbors to be investigated
            Neighboors=[
                -1   ,  0;
                1   ,  0;
                0   , -1;
                0   ,  1; 
                        ];
        
            Neighboors = Neighboors*maxsize;
            N_Neighboors=4; %number of checks
            
            %%%%%%%%% Creating Heuristic-matrix based on distance to nearest  goal node
            [col, row]=find(GoalRegister==1); %finds col and row of goal point
            RegisteredGoals=[row col];
            Nodesfound=size(RegisteredGoals,1);
            
            for k=1:size(GoalRegister,1)
                for j=1:size(GoalRegister,2)
                    if MAP(k,j)==0
                        Mat=RegisteredGoals-(repmat([j k],(Nodesfound),1));
                        Distance=(min(sqrt(sum(abs(Mat).^2,2))));
                        Hn(k,j)=Distance;
                    end
                end
            end
            %End of creating Heuristic-matrix.
            %Note: If Hn values is set to zero the method will reduce to the Dijkstras method.
            
            %Initializign start node with FValue and opening first node.
            FScore(StartY,StartX)=Hn(StartY,StartX);   %Start f score at starting point      
            OpenMAT(StartY,StartX)=1;   

            while 1==1 %Code will break when path found or when no path exist
                MINopenFSCORE=min(min(FScore));
                
                if MINopenFSCORE==inf%check for failure
                    OptimalPath=inf;
                    RECONSTRUCTPATH=0;
                    break;
                end
                
                [CurrentY,CurrentX]=find(FScore==MINopenFSCORE);
                CurrentY=CurrentY(1);
                CurrentX=CurrentX(1);
                
                if GoalRegister(CurrentY,CurrentX)==1
                    %GOAL!!
                    RECONSTRUCTPATH=1;
                    break
                end
                
    
                %Removing node from OpenList to ClosedList  
                OpenMAT(CurrentY,CurrentX)=0;
                FScore(CurrentY,CurrentX)=inf;
                ClosedMAT(CurrentY,CurrentX)=1;
    
                for p=1:N_Neighboors
                    
                    i=Neighboors(p,1); %Y
                    j=Neighboors(p,2); %X
                    
                    %check for the boundaries
                    
                    if CurrentY+i<1||CurrentY+i>Height||CurrentX+j<1||CurrentX+j>Width
                        continue
                    end
                    Flag=1;
                    
                    if(ClosedMAT(CurrentY+i,CurrentX+j)==0) %Neiboor is open;
                                
                        if (abs(i)>1||abs(j)>1) %goes from 1- i or j whichever is above 1
                            % Need to check that the path does not pass an object
                            JumpCells=2*max(abs(i),abs(j))-1;
                            for K=1:JumpCells
                                YPOS=round(K*i/JumpCells);
                                XPOS=round(K*j/JumpCells);
            
                                if (MAP(CurrentY+YPOS,CurrentX+XPOS)==1)
                                    Flag=0;
                                end
                    
                                if GoalRegister(CurrentY+YPOS,CurrentX+XPOS)==1
                                    %GOAL!!
                                    RECONSTRUCTPATH=1;
                                break
                                end
                            end
                        end
                        %End of  checking that the path does not pass an object
                        
                        if Flag==1
                            tentative_gScore = GScore(CurrentY,CurrentX) + sqrt(i^2+j^2);
                            if OpenMAT(CurrentY+i,CurrentX+j)==0
                                OpenMAT(CurrentY+i,CurrentX+j)=1;                    
                            elseif tentative_gScore >= GScore(CurrentY+i,CurrentX+j)
                                continue
                            end
                            ParentX(CurrentY+i,CurrentX+j)=CurrentX;
                            ParentY(CurrentY+i,CurrentX+j)=CurrentY;
                            GScore(CurrentY+i,CurrentX+j)=tentative_gScore;
                            FScore(CurrentY+i,CurrentX+j)= tentative_gScore+Hn(CurrentY+i,CurrentX+j);
                        end
                    end%if(ClosedMAT(CurrentY+i,CurrentX+j)==0) end
                end%for p=1:N_Neighboors loop end
            end%%while loop end

            k=2;
            if RECONSTRUCTPATH
                OptimalPath(1,:)=[CurrentY CurrentX];
                
                while RECONSTRUCTPATH
                    
                    PrevXTemp=ParentX(CurrentY,CurrentX);%put previous x in xTemp
                    PrevYTemp=ParentY(CurrentY,CurrentX); %put previous y in yTemp
                    
                    CurrentY=PrevYTemp;
                    CurrentX=PrevXTemp;
                    OptimalPath(k,:)=[CurrentY CurrentX];
                    k=k+1;
                    if (((CurrentX== StartX)) &&(CurrentY==StartY))
                        break
                    end
                end
            end
        end% A*function end


    end%this is end for this entire section not function
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            set(app.Bot1DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot2DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot3DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot4DropDown,'Items',app.btNames.RemoteNames);
            
           % setOccupancyMap
            

            imagesc(app.Map,((app.MAP*40)+(app.GoalRegister)*5));
            colormap(app.Map,flipud(hot));
        end

        % Button pushed function: ConnectButton_1
        function ConnectButton_1Pushed(app, event)
           
            
            app.Bot1DropDown.Enable = 'off';
            app.ConnectButton_1.Enable = 'off';
            addText(app,'Connecting to Bot 1....');
            pause(.01);
            try
                app.Bot1bt = Bluetooth(app.Bot1DropDown.Value,1);
                fopen(app.Bot1bt); 
                app.Bot1bt.Timeout = 120;
                addText(app,'Bot 1: CONNECTED');
                fwrite(app.Bot1bt,1);
                app.Lamp1.Color = 'green'; 
            catch
                app.Bot1DropDown.Enable = "on";
                app.ConnectButton_1.Enable = 'on';
                addText(app,'ERROR Connecting to Bot 1');
            end
             
        end

        % Button pushed function: ConnectButton_3
        function ConnectButton_3Pushed(app, event)
           
            
            app.Bot3DropDown.Enable = "off";
            app.ConnectButton_3.Enable = 'off';
            addText(app,'Connecting to Bot 3....');
            pause(.01);
            try
                app.Bot3bt = Bluetooth(app.Bot3DropDown.Value,1);
                fopen(app.Bot3bt);
                app.Bot3bt.Timeout = 120;
                addText(app,'Bot 3: CONNECTED');
                fwrite(app.Bot3bt,1);
                app.Lamp3.Color = 'green';
            catch
                app.Bot3DropDown.Enable = "on";
                app.ConnectButton_3.Enable = 'on';
                addText(app,'ERROR Connecting to Bot 3');             
            end
            
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            
            
            app.Bot2DropDown.Enable = "off";
            app.ConnectButton_2.Enable = 'off';
            addText(app,'Connecting to Bot 2....');
            pause(.01);
            try
                app.Bot2bt = Bluetooth(app.Bot2DropDown.Value,1);
                fopen(app.Bot2bt);
                app.Bot2bt.Timeout = 120;
                addText(app,'Bot 2: CONNECTED');
                fwrite(app.Bot2bt,1);
                app.Lamp2.Color = 'green';
            catch
                app.Bot2DropDown.Enable = "on";
                app.ConnectButton_2.Enable = 'on';
                addText(app,'ERROR Connecting to Bot 2');              
            end
            
        end

        % Button pushed function: ConnectButton_4
        function ConnectButton_4Pushed(app, event)
           
            
            app.Bot4DropDown.Enable = "off";
            app.ConnectButton_4.Enable = 'off';
            addText(app,'Connecting to Bot 4....');
            pause(.01); 
            try
                app.Bot4bt = Bluetooth(app.Bot4DropDown.Value,1);
                fopen(app.Bot4bt); 
                app.Bot4bt.Timeout = 120;
                addText(app,'Bot 4: CONNECTED');
                fwrite(app.Bot4bt,1);
                app.Lamp4.Color = 'green';
            catch                
                app.Bot4DropDown.Enable = "on";
                app.ConnectButton_4.Enable = 'on';
                addText(app,'ERROR Connecting to Bot 4');
            end
            
        end

        % Value changed function: WASDControlButton
        function WASDControlButtonValueChanged(app, event)
            app.wasdvalue = app.WASDControlButton.Value;        % Update wasdvalue to true or false to let keymovement be pressed
        end

        % Key press function: MovingCompanyRobotsUIFigure
        function MovingCompanyRobotsUIFigureKeyPress(app, event)
            key = event.Key;
            if app.wasdvalue == 1
                switch key
                    case 'w'
                        fwrite(app.Bot1bt,2);
                    case 'a'
                        fwrite(app.Bot1bt,5);
                    case 's'
                        fwrite(app.Bot1bt,3);
                    case 'd'
                        fwrite(app.Bot1bt,4);    
                end
            end
            
        end

        % Key release function: MovingCompanyRobotsUIFigure
        function MovingCompanyRobotsUIFigureKeyRelease(app, event)
           if app.wasdvalue == 1
            fwrite(app.Bot1bt,0);
           end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.destx = value;
            [Height,Width]=size(app.MAP);               %Height and width of matrix
            app.GoalRegister=double(zeros(Height,Width));
            app.GoalRegister(app.desty-3,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty-2,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty-1,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty+1,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty+2,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty+3,app.destx-3:app.destx+3) = 1;
            imagesc(app.Map,((app.MAP*40)+(app.GoalRegister)*5));
            colormap(app.Map,flipud(hot));

        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.desty = value;
            [Height,Width]=size(app.MAP);               %Height and width of matrix
            app.GoalRegister=double(zeros(Height,Width));
            app.GoalRegister(app.desty-3,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty-2,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty-1,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty+1,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty+2,app.destx-3:app.destx+3) = 1;
            app.GoalRegister(app.desty+3,app.destx-3:app.destx+3) = 1;
            imagesc(app.Map,((app.MAP*40)+(app.GoalRegister)*5));
            colormap(app.Map,flipud(hot));
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            if app.finishedpath == 1
                
                currentx = app.OptimalPath(end,2);
                currenty = app.OptimalPath(end,1);
                k = 1;
                xpath = 0; %used to tell whether path is falling purely the x path 
                ypath =0;   %used to tell whether path is falling purely the x path 
                xdir = 0; % 0 for positive ,1 for negative
                ydir = 1; % 0 for positive ,1 for negative
                
                for i = size(app.OptimalPath,1):-1:2
                    nextx = app.OptimalPath((i-1),2);
                    nexty = app.OptimalPath((i-1),1);
                    
                    %changed directions from vertical to horizontal
                     if nextx ~= currentx && xpath == 1% Rotate either right or left depending on y 
                         %if going straight ahead of us
                         if ydir == 1
                             if nextx >currentx% Rotate Right
                                    app.Transpose(k,:) = [114 app.turn90];
                                    xdir = 0;
                             else%Rotate left is nextx<currentx
                                    app.Transpose(k,:) = [108 app.turn90];
                                    xdir = 1;
                             end
                             xpath = 0;
                             k=k+1;
                         % if coming in pos direction; towards us   
                         else
                             if nextx >currentx% Rotate left
                                    app.Transpose(k,:) = [108 app.turn90];
                                    xdir = 0;
                                 
                             else%Rotate righ is nextx<currentx
                                    app.Transpose(k,:) = [114 app.turn90];
                                    xdir = 1;
                             end
                             xpath = 0;
                             k=k+1;
                         end
                     end
                     
                      %changed directions from horizontal to vertical
                    if nexty ~= currenty && ypath == 1% Rotate either right or left depending on y
                        if xdir == 0%going right
                            if nexty >currenty% Rotate Right
                                app.Transpose(k,:) = [114 app.turn90];
                                ydir = 0;
                            else%Rotate left
                                app.Transpose(k,:) = [108 app.turn90];
                                ydir = 1;
                            end
                            ypath = 0;
                            k=k+1; 
                        else
                            if nexty >currenty% Rotate Left
                                app.Transpose(k,:) = [108 app.turn90];
                                ydir = 0;
                            else %Rotate Right
                                app.Transpose(k,:) = [114 app.turn90];
                                ydir = 1;
                            end
                            ypath = 0;
                            k=k+1; 
                        
                        end
          
                    end
                    
                         
                    if nextx == currentx% only y changed going forward                 
                        app.Transpose(k,:) = [102 abs(currenty - nexty)];
                        xpath =1;
                        k=k+1;
        
                    end
                    
                    if nexty == currenty% only x changed going forward
                        app.Transpose(k,:) = [102 abs(currentx - nextx)];
                        ypath =1;
                        k=k+1;
                    
                    end
                    
                    currentx = nextx;
                    currenty = nexty;
                end%forloop end
                
                %Begin EXECUTION of PATH!!@#!@#
                for i=1:1:size(app.Transpose,1)
                    x = app.Transpose(i,1);
                    y = app.Transpose(i,2);
                end
                
            else
                addText(app, "No Path has been found yet")
            end
        end

        % Button pushed function: ReScanButton
        function ReScanButtonPushed(app, event)
            addText(app,'Resanning for Bluetooth Devices...');
            pause(.01); 
            app.btNames = instrhwinfo('Bluetooth');
            set(app.Bot1DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot2DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot3DropDown,'Items',app.btNames.RemoteNames);
            set(app.Bot4DropDown,'Items',app.btNames.RemoteNames);
            
            app.Lamp1.Color = 'red';                
            app.Bot1DropDown.Enable = "on";    
            app.ConnectButton_1.Enable = 'on';
            app.Lamp2.Color = 'red';                
            app.Bot2DropDown.Enable = "on";    
            app.ConnectButton_2.Enable = 'on';
            app.Lamp3.Color = 'red';                
            app.Bot3DropDown.Enable = "on";    
            app.ConnectButton_3.Enable = 'on';
            app.Lamp4.Color = 'red';                
            app.Bot4DropDown.Enable = "on";    
            app.ConnectButton_4.Enable = 'on';
            addText(app,'SCAN COMPLETE');
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            switch app.BotDropDown.Value%Go forward some ticks*10
                case 0%all
                    fwrite(app.Bot1bt,uint8([102,app.encval]));
                    fwrite(app.Bot2bt,uint8([102,app.encval]));
                    fwrite(app.Bot3bt,uint8([102,app.encval]));
                    fwrite(app.Bot4bt,uint8([102,app.encval]));
                case 1%bot1
                    fwrite(app.Bot1bt,uint8([102,app.encval]));
                case 2%bot2
                    fwrite(app.Bot2bt,uint8([102,app.encval]));
                case 3%bot3
                    fwrite(app.Bot3bt,uint8([102,app.encval]));
                case 4%bot4   
                    fwrite(app.Bot4bt,uint8([102,app.encval]));
            end            
        end

        % Button pushed function: SpeedCalibrationButton
        function SpeedCalibrationButtonPushed(app, event)
            addText(app,'Starting Speed Calibration');
            pause(.01); 
             switch app.BotDropDown.Value%Speed Calibration
                case 0%all
                     fwrite(app.Bot1bt,115); 
                     fread(app.Bot1bt,1);
                     fwrite(app.Bot2bt,115); 
                     fread(app.Bot2bt,1);
                     fwrite(app.Bot3bt,115); 
                     fread(app.Bot3bt,1);
                     fwrite(app.Bot4bt,115); 
                     app.read=fread(app.Bot4bt,1);
                    if app.read == 99
                        addText(app,'Speed Calibration: COMPLETE');
                        app.read = 0;
                    end
                case 1%bot1
                    fwrite(app.Bot1bt,115); 
                    app.read = fread(app.Bot1bt,1);
                    if app.read == 99
                        addText(app,'Speed Calibration: COMPLETE');
                        app.read = 0;
                    end
                case 2%bot2
                    fwrite(app.Bot2bt,115); 
                    app.read = fread(app.Bot2bt,1);
                    if app.read == 99
                        addText(app,'Speed Calibration: COMPLETE');
                        app.read = 0;
                    end
                case 3%bot3
                    fwrite(app.Bot3bt,115); 
                    app.read = fread(app.Bot3bt,1);
                    if app.read == 99
                        addText(app,'Speed Calibration: COMPLETE');
                        app.read = 0;
                    end
                case 4%bot4   
                    ffwrite(app.Bot4bt,115); 
                    app.read = fread(app.Bot4bt,1);
                    if app.read == 99
                        addText(app,'Speed Calibration: COMPLETE');
                        app.read = 0;
                    end
             end 
             
            fwrite(app.Bot1bt,115); 
            app.read = fread(app.Bot1bt,1);
           if app.read == 99
            addText(app,'Speed Calibration: COMPLETE');
            app.read = 0;
           end
        end

        % Value changed function: TicksEditField
        function TicksEditFieldValueChanged(app, event)
            value = app.TicksEditField.Value; %number of ticks
            app.encval = value;
        end

        % Button pushed function: RotateRight90Button
        function RotateRight90ButtonPushed(app, event)
           switch app.BotDropDown.Value%Rotate right some ticks
                case 0%all
                    fwrite(app.Bot1bt,uint8([114,app.encval]))
                    fwrite(app.Bot2bt,uint8([114,app.encval]))
                    fwrite(app.Bot3bt,uint8([114,app.encval]))
                    fwrite(app.Bot4bt,uint8([114,app.encval]))
                case 1%bot1
                    fwrite(app.Bot1bt,uint8([114,app.encval]))
                case 2%bot2
                    fwrite(app.Bot2bt,uint8([114,app.encval]))
                case 3%bot3
                    fwrite(app.Bot3bt,uint8([114,app.encval]))
                case 4%bot4   
                    fwrite(app.Bot4bt,uint8([114,app.encval]))
            end      
        end

        % Button pushed function: RotateLeft90Button
        function RotateLeft90ButtonPushed(app, event)
            switch app.BotDropDown.Value %Rotate left some encval ticks
                case 0%all
                    fwrite(app.Bot1bt,uint8([108,app.encval]))
                    fwrite(app.Bot2bt,uint8([108,app.encval]))
                    fwrite(app.Bot3bt,uint8([108,app.encval]))
                    fwrite(app.Bot4bt,uint8([108,app.encval]))
                case 1%bot1
                    fwrite(app.Bot1bt,uint8([108,app.encval]))
                case 2%bot2
                    fwrite(app.Bot2bt,uint8([108,app.encval]))
                case 3%bot3
                    fwrite(app.Bot3bt,uint8([108,app.encval]))
                case 4%bot4   
                    fwrite(app.Bot4bt,uint8([108,app.encval]))
            end      
            f
        end

        % Button pushed function: FindPathButton
        function FindPathButtonPushed(app, event)
            app.OptimalPath=astarfunc(app,app.StartX,app.StartY,app.MAP,app.GoalRegister,app.sizeofbot);
            if size(app.OptimalPath,2)>1
                plot(app.Map,app.OptimalPath(1,2),app.OptimalPath(1,1),'x','color','k')
                plot(app.Map,app.OptimalPath(end,2),app.OptimalPath(end,1),'o','color','b')
                plot(app.Map,app.OptimalPath(:,2),app.OptimalPath(:,1),'r')
                addText(app,'!!FINISHED PATH!!');
                app.finishedpath = 1;
                pause(.01);   
            else
                addText(app,'NO PATH EXIST TRY Different distination');
            end

            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MovingCompanyRobotsUIFigure = uifigure;
            app.MovingCompanyRobotsUIFigure.Name = 'Moving Company Robots';
            app.MovingCompanyRobotsUIFigure.KeyPressFcn = createCallbackFcn(app, @MovingCompanyRobotsUIFigureKeyPress, true);
            app.MovingCompanyRobotsUIFigure.KeyReleaseFcn = createCallbackFcn(app, @MovingCompanyRobotsUIFigureKeyRelease, true);
            app.BotBlueToothConnectionPanel = uipanel(app.MovingCompanyRobotsUIFigure);
            app.BotBlueToothConnectionPanel.Title = 'Bot BlueTooth Connection';
            app.Lamp1 = uilamp(app.BotBlueToothConnectionPanel);
            app.Lamp2 = uilamp(app.BotBlueToothConnectionPanel);
            app.Lamp3 = uilamp(app.BotBlueToothConnectionPanel);
            app.Lamp4 = uilamp(app.BotBlueToothConnectionPanel);
            app.Bot1DropDownLabel = uilabel(app.BotBlueToothConnectionPanel);
            app.Bot1DropDownLabel.Text = 'Bot 1';
            app.Bot1DropDown = uidropdown(app.BotBlueToothConnectionPanel);
            app.Bot2DropDownLabel = uilabel(app.BotBlueToothConnectionPanel);
            app.Bot2DropDownLabel.Text = 'Bot 2';
            app.Bot2DropDown = uidropdown(app.BotBlueToothConnectionPanel);
            app.Bot3DropDownLabel = uilabel(app.BotBlueToothConnectionPanel);
            app.Bot3DropDownLabel.Text = 'Bot 3';
            app.Bot3DropDown = uidropdown(app.BotBlueToothConnectionPanel);
            app.Bot4DropDownLabel = uilabel(app.BotBlueToothConnectionPanel);
            app.Bot4DropDownLabel.Text = 'Bot 4';
            app.Bot4DropDown = uidropdown(app.BotBlueToothConnectionPanel);
            app.ConnectButton_1 = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.ConnectButton_1.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_1Pushed, true);
            app.ConnectButton_1.Text = 'Connect';
            app.ConnectButton_2 = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.ConnectButton_3 = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.ConnectButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_3Pushed, true);
            app.ConnectButton_3.Text = 'Connect';
            app.ConnectButton_4 = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.ConnectButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_4Pushed, true);
            app.ConnectButton_4.Text = 'Connect';
            app.ReScanButton = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.ReScanButton.ButtonPushedFcn = createCallbackFcn(app, @ReScanButtonPushed, true);
            app.ReScanButton.Text = 'ReScan';
            app.SpeedCalibrationButton = uibutton(app.BotBlueToothConnectionPanel, 'push');
            app.SpeedCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedCalibrationButtonPushed, true);
            app.SpeedCalibrationButton.Text = 'Speed Calibration';
            app.ControlsPanel = uipanel(app.MovingCompanyRobotsUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.TabGroup = uitabgroup(app.ControlsPanel);
            app.FormationControlTab = uitab(app.TabGroup);
            app.FormationControlTab.Title = 'Formation Control';
            app.BoxButton = uibutton(app.FormationControlTab, 'push');
            app.BoxButton.Text = 'Box';
            app.CylinderButton = uibutton(app.FormationControlTab, 'push');
            app.CylinderButton.Text = 'Cylinder';
            app.TButton = uibutton(app.FormationControlTab, 'push');
            app.TButton.Text = 'T';
            app.NavigationTab = uitab(app.TabGroup);
            app.NavigationTab.Title = 'Navigation';
            app.XEditFieldLabel = uilabel(app.NavigationTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.NavigationTab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.SetDestinationLabel = uilabel(app.NavigationTab);
            app.SetDestinationLabel.Text = 'Set Destination';
            app.YEditFieldLabel = uilabel(app.NavigationTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.NavigationTab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.BeginButton = uibutton(app.NavigationTab, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.FindPathButton = uibutton(app.NavigationTab, 'push');
            app.FindPathButton.ButtonPushedFcn = createCallbackFcn(app, @FindPathButtonPushed, true);
            app.FindPathButton.Text = 'Find Path';
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.BotDropDownLabel = uilabel(app.ManualTab);
            app.BotDropDownLabel.Text = 'Bot';
            app.BotDropDown = uidropdown(app.ManualTab);
            app.BotDropDown.Items = {'All', '1', '2', '3', '4'};
            app.BotDropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.BotDropDown.Value = '0';
            app.WASDControlButton = uibutton(app.ManualTab, 'state');
            app.WASDControlButton.ValueChangedFcn = createCallbackFcn(app, @WASDControlButtonValueChanged, true);
            app.WASDControlButton.Text = 'WASD Control';
            app.ForwardButton = uibutton(app.ManualTab, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.RotateRight90Button = uibutton(app.ManualTab, 'push');
            app.RotateRight90Button.ButtonPushedFcn = createCallbackFcn(app, @RotateRight90ButtonPushed, true);
            app.RotateRight90Button.Text = 'Rotate Right 90';
            app.RotateLeft90Button = uibutton(app.ManualTab, 'push');
            app.RotateLeft90Button.ButtonPushedFcn = createCallbackFcn(app, @RotateLeft90ButtonPushed, true);
            app.RotateLeft90Button.Text = 'Rotate Left 90';
            app.TicksEditFieldLabel = uilabel(app.ManualTab);
            app.TicksEditFieldLabel.Text = 'Ticks';
            app.TicksEditField = uieditfield(app.ManualTab, 'numeric');
            app.TicksEditField.ValueChangedFcn = createCallbackFcn(app, @TicksEditFieldValueChanged, true);
            app.Panel = uipanel(app.MovingCompanyRobotsUIFigure);
            app.Panel.Title = 'Panel';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.Editable = 'off';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.Text = 'Reset ';
            app.TabGroup2 = uitabgroup(app.MovingCompanyRobotsUIFigure);
            app.NavigationTab_2 = uitab(app.TabGroup2);
            app.NavigationTab_2.Title = 'Navigation';
            app.Map = uiaxes(app.NavigationTab_2);
            title(app.Map, '')
            xlabel(app.Map, 'X')
            ylabel(app.Map, 'Y')
            app.Map.XLim = [0 100];
            app.Map.YLim = [0 100];
            app.Map.YDir = 'reverse';
            app.Map.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.Map.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.Map.YTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.Map.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Moving_Comapny_Robots_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MovingCompanyRobotsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MovingCompanyRobotsUIFigure)
        end
    end
end